classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofSubcarrierEditFieldLabel  matlab.ui.control.Label
        NumberofSubcarrierEditField    matlab.ui.control.NumericEditField
        ModulationOrderEditFieldLabel  matlab.ui.control.Label
        ModulationOrderEditField       matlab.ui.control.NumericEditField
        NumberofCPEditFieldLabel       matlab.ui.control.Label
        NumberofCPEditField            matlab.ui.control.NumericEditField
        FramenumberEditFieldLabel      matlab.ui.control.Label
        FramenumberEditField           matlab.ui.control.NumericEditField
        ParameterofOFDMsingalLabel     matlab.ui.control.Label
        OFDMCosimulationSystemLabel    matlab.ui.control.Label
        ParameterofraisedcosinefilterLabel  matlab.ui.control.Label
        rollofffactorEditFieldLabel    matlab.ui.control.Label
        rollofffactorEditField         matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel     matlab.ui.control.Label
        SamplingrateEditField          matlab.ui.control.NumericEditField
        UpsamplingrateEditFieldLabel   matlab.ui.control.Label
        UpsamplingrateEditField        matlab.ui.control.NumericEditField
        TransmitButton                 matlab.ui.control.Button
        ReceiveButton                  matlab.ui.control.Button
        BitErrorRateEditFieldLabel     matlab.ui.control.Label
        BitErrorRateEditField          matlab.ui.control.NumericEditField
        PackgeErrorRateEditFieldLabel  matlab.ui.control.Label
        PackgeErrorRateEditField       matlab.ui.control.NumericEditField
        MonitoredSubcarrierIndexEditFieldLabel  matlab.ui.control.Label
        MonitoredSubcarrierIndexEditField  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        PAPRdBEditFieldLabel           matlab.ui.control.Label
        PAPRdBEditField                matlab.ui.control.NumericEditField
        EVMrmsEditFieldLabel           matlab.ui.control.Label
        EVMrmsEditField                matlab.ui.control.NumericEditField
        EVMmaxEditFieldLabel           matlab.ui.control.Label
        EVMmaxEditField                matlab.ui.control.NumericEditField
        EVMpercentageEditFieldLabel    matlab.ui.control.Label
        EVMpercentageEditField         matlab.ui.control.NumericEditField
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NumberofSubcarrier double % Description
        ModulationOrder double
        NumberofCP double
        Framenumber double
        rollofffactor double
        Samplingrate double
        Upsamplingrate double
        BitErrorRate double
        PackgeErrorRate double
        BitErrorNum double
        PackgeErrorNum double
        MonitoredSubcarrier double
        BitSequenOri double
        Pilotmod double
        TimeArrayInterp double
        DigitalOutputRoll double
        RXSymbolsOnSpecificCarrier double
        TXSymbolsOnSpecificCarrier double
        TXPAPR double
        EVMrms double
        EVMmax double
        EVMpercent double
        nSymbol double
        DigitalReceivedRoll double
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NumberofSubcarrier, ModulationOder, NumberofCP, Framenumber, rollofffactor, Samplingrate, Upsamplingrate, BitErrorRate, PackageErrorRate, MonitoredSubcarrier, TXPAPR, EVMrms, EVMmax, EVMpercent)
            app.NumberofSubcarrier = app.NumberofSubcarrierEditField.Value;
            app.ModulationOrder = app.ModulationOrderEditField.Value;
            app.NumberofCP = app.NumberofCPEditField.Value;
            app.Framenumber = app.FramenumberEditField.Value;
            app.rollofffactor = app.rollofffactorEditField.Value;
            app.Samplingrate = app.SamplingrateEditField.Value;
            app.Upsamplingrate = app.UpsamplingrateEditField.Value;
            app.MonitoredSubcarrier = app.MonitoredSubcarrierIndexEditField.Value;
            app.BitErrorRate = 0;
            app.PackgeErrorRate = 0;
            app.TXPAPR = 0;
            app.EVMrms = 0;
            app.EVMmax = 0;
            app.EVMpercent = 0;
            app.BitErrorRateEditField.Value = app.BitErrorRate;
            app.PackgeErrorRateEditField.Value = app.PackgeErrorRate;
            app.PAPRdBEditField.Value = app.TXPAPR;
            app.EVMrmsEditField.Value = app.EVMrms;
            app.EVMmaxEditField.Value = app.EVMmax;
            app.EVMpercentageEditField.Value = app.EVMpercent;
        end

        % Button pushed function: TransmitButton
        function TransmitButtonPushed(app, event)
           app.Framenumber = app.FramenumberEditField.Value;
           app.Upsamplingrate = app.UpsamplingrateEditField.Value;
           [app.BitSequenOri, app.Pilotmod,app.TimeArrayInterp,app.DigitalOutputRoll,app.TXSymbolsOnSpecificCarrier,app.TXPAPR] = Top_transmitter_gui (app.NumberofSubcarrier, app.ModulationOrder, app.NumberofCP, app.Framenumber, app.rollofffactor, app.Samplingrate, app.Upsamplingrate,app.MonitoredSubcarrier);
           app.PAPRdBEditField.Value = app.TXPAPR;
           plot(app.UIAxes,app.TimeArrayInterp,app.DigitalOutputRoll);
        end

        % Button pushed function: ReceiveButton
        function ReceiveButtonPushed(app, event)
            [app.DigitalReceivedRoll, app.BitErrorNum, app.BitErrorRate, app.PackgeErrorNum, app.PackgeErrorRate,app.RXSymbolsOnSpecificCarrier] = Top_receiver_gui (app.NumberofSubcarrier, app.ModulationOrder, app.NumberofCP, app.Framenumber, app.rollofffactor, app.Samplingrate,app.Upsamplingrate ,app.Pilotmod,app.BitSequenOri,app.DigitalOutputRoll,app.MonitoredSubcarrier);
            plot(app.UIAxes3,app.TimeArrayInterp,app.DigitalReceivedRoll(1:length(app.TimeArrayInterp)));
            scatter(app.UIAxes2, real(app.RXSymbolsOnSpecificCarrier),imag(app.RXSymbolsOnSpecificCarrier),'*');
            app.BitErrorRateEditField.Value = app.BitErrorRate;
            app.PackgeErrorRateEditField.Value = app.PackgeErrorRate;
            [app.EVMrms,app.EVMmax,app.EVMpercent,app.nSymbol] =  Evm_Calculation (app.TXSymbolsOnSpecificCarrier.', app.RXSymbolsOnSpecificCarrier.');
            app.EVMrmsEditField.Value = app.EVMrms;
            app.EVMmaxEditField.Value = app.EVMmax; 
            app.EVMpercentageEditField.Value = app.EVMpercent;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofSubcarrierEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSubcarrierEditFieldLabel.Text = 'Number of Subcarrier';
            app.NumberofSubcarrierEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSubcarrierEditField.Value = 256;
            app.ModulationOrderEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationOrderEditFieldLabel.Text = 'Modulation Order';
            app.ModulationOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulationOrderEditField.Value = 4;
            app.NumberofCPEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCPEditFieldLabel.Text = 'Number of CP';
            app.NumberofCPEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCPEditField.Value = 16;
            app.FramenumberEditFieldLabel = uilabel(app.UIFigure);
            app.FramenumberEditFieldLabel.Text = 'Frame number';
            app.FramenumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramenumberEditField.Value = 20;
            app.ParameterofOFDMsingalLabel = uilabel(app.UIFigure);
            app.ParameterofOFDMsingalLabel.Text = 'Parameter of OFDM singal';
            app.OFDMCosimulationSystemLabel = uilabel(app.UIFigure);
            app.OFDMCosimulationSystemLabel.Text = 'OFDM Co-simulation System';
            app.ParameterofraisedcosinefilterLabel = uilabel(app.UIFigure);
            app.ParameterofraisedcosinefilterLabel.Text = 'Parameter of raised cosine filter';
            app.rollofffactorEditFieldLabel = uilabel(app.UIFigure);
            app.rollofffactorEditFieldLabel.Text = 'rolloff factor';
            app.rollofffactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollofffactorEditField.Value = 0.25;
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.Value = 1000000000;
            app.UpsamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.UpsamplingrateEditFieldLabel.Text = 'Up- sampling rate';
            app.UpsamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpsamplingrateEditField.Value = 4;
            app.TransmitButton = uibutton(app.UIFigure, 'push');
            app.TransmitButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitButtonPushed, true);
            app.TransmitButton.Text = 'Transmit !';
            app.ReceiveButton = uibutton(app.UIFigure, 'push');
            app.ReceiveButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveButtonPushed, true);
            app.ReceiveButton.Text = 'Receive !';
            app.BitErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PackgeErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.PackgeErrorRateEditFieldLabel.Text = 'Packge Error Rate';
            app.PackgeErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonitoredSubcarrierIndexEditFieldLabel = uilabel(app.UIFigure);
            app.MonitoredSubcarrierIndexEditFieldLabel.Text = 'Monitored Subcarrier Index';
            app.MonitoredSubcarrierIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonitoredSubcarrierIndexEditField.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmitted Waveform')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Constellation')
            xlabel(app.UIAxes2, 'I-PATH')
            ylabel(app.UIAxes2, 'Q-PATH')
            app.PAPRdBEditFieldLabel = uilabel(app.UIFigure);
            app.PAPRdBEditFieldLabel.Text = 'PAPR (dB)';
            app.PAPRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.EVMrmsEditFieldLabel = uilabel(app.UIFigure);
            app.EVMrmsEditFieldLabel.Text = 'EVM rms';
            app.EVMrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EVMmaxEditFieldLabel = uilabel(app.UIFigure);
            app.EVMmaxEditFieldLabel.Text = 'EVM max';
            app.EVMmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.EVMpercentageEditFieldLabel = uilabel(app.UIFigure);
            app.EVMpercentageEditFieldLabel.Text = 'EVM percentage';
            app.EVMpercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Received Waveform')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Voltage')
        end
    end

    methods (Access = public)

        % Construct app
        function app = App(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end