classdef App_Ver2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModulationOrderEditFieldLabel   matlab.ui.control.Label
        ModulationOrderEditField        matlab.ui.control.NumericEditField
        NumberofCPEditFieldLabel        matlab.ui.control.Label
        NumberofCPEditField             matlab.ui.control.NumericEditField
        FrameNumberEditFieldLabel       matlab.ui.control.Label
        FrameNumberEditField            matlab.ui.control.NumericEditField
        ParameterofOFDMsingalLabel      matlab.ui.control.Label
        ParameterofraisedcosinefilterLabel  matlab.ui.control.Label
        RolloffFactorEditFieldLabel     matlab.ui.control.Label
        RolloffFactorEditField          matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        UpsamplingRateEditFieldLabel    matlab.ui.control.Label
        UpsamplingRateEditField         matlab.ui.control.NumericEditField
        SignalModulationButton          matlab.ui.control.Button
        SignalDemodulationButton        matlab.ui.control.Button
        BitErrorRateEditFieldLabel      matlab.ui.control.Label
        BitErrorRateEditField           matlab.ui.control.NumericEditField
        PackgeErrorRateEditFieldLabel   matlab.ui.control.Label
        PackgeErrorRateEditField        matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        PAPRdBEditFieldLabel            matlab.ui.control.Label
        PAPRdBEditField                 matlab.ui.control.NumericEditField
        DataRatebpsEditFieldLabel       matlab.ui.control.Label
        DataRatebpsEditField            matlab.ui.control.NumericEditField
        EVMEditFieldLabel               matlab.ui.control.Label
        EVMEditField                    matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        MatlabADSCadenceCosimulationPlatformLabel  matlab.ui.control.Label
        MatlabADSCadenceCosimulationPlatformLabel_2  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        NumberofSubcarrierEditFieldLabel  matlab.ui.control.Label
        NumberofSubcarrierEditField     matlab.ui.control.NumericEditField
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        RFBandwidthGHzEditFieldLabel    matlab.ui.control.Label
        RFBandwidthGHzEditField         matlab.ui.control.NumericEditField
        CharacteristicsofsignalLabel    matlab.ui.control.Label
        ParameterofreceivedsingalLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        SamplingPointNumberEditFieldLabel  matlab.ui.control.Label
        SamplingPointNumberEditField    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        NumberofSubcarrier double % Description
        ModulationOrder double
        NumberofCP double
        Framenumber double
        rollofffactor double
        Samplingrate double
        Upsamplingrate double
        BitErrorRate double
        PackgeErrorRate double
        BitErrorNum double
        PackgeErrorNum double
        MonitoredSubcarrier double
        BitSequenOri double
        Pilotmod double
        TimeArrayInterp double
        DigitalOutputRoll double
        RXSymbolsOnSpecificCarrier double
        TXSymbolsOnSpecificCarrier double
        TXPAPR double
        EVMrms double
        EVMmax double
        EVMpercent double
        nSymbol double
        DigitalReceivedRoll double
        SamplingPointNum double
        RFbandwidthGHz double
        Datarate double
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NumberofSubcarrier, ModulationOder, NumberofCP, Framenumber, rollofffactor, Samplingrate, Upsamplingrate, BitErrorRate, PackageErrorRate, MonitoredSubcarrier, TXPAPR, EVMrms, EVMmax, EVMpercent)
            app.NumberofSubcarrier = app.NumberofSubcarrierEditField.Value;
            app.ModulationOrder = app.ModulationOrderEditField.Value;
            app.NumberofCP = app.NumberofCPEditField.Value;
            app.Framenumber = app.FrameNumberEditField.Value;
            app.rollofffactor = app.RolloffFactorEditField.Value;
            app.Samplingrate = app.SamplingRateHzEditField.Value;
            app.Upsamplingrate = app.UpsamplingRateEditField.Value;
%             app.MonitoredSubcarrier = app.SubcarrierIndexEditField.Value;
            app.BitErrorRate = 0;
            app.PackgeErrorRate = 0;
            app.TXPAPR = 0;
            app.EVMpercent = 0;
            app.SamplingPointNum = 0;
            app.RFbandwidthGHz = 0;
            app.BitErrorRateEditField.Value = app.BitErrorRate;
            app.PackgeErrorRateEditField.Value = app.PackgeErrorRate;
            app.PAPRdBEditField.Value = app.TXPAPR;
            app.Datarate = 0;
            app.EVMEditField.Value = app.EVMpercent;
            app.SamplingPointNumberEditField.Value = app.SamplingPointNum;
            app.RFBandwidthGHzEditField.Value = app.RFbandwidthGHz;
            app.DataRatebpsEditField.Value = app.Datarate;
        end

        % Button pushed function: SignalModulationButton
        function SignalModulationButtonPushed(app, event)
           app.NumberofSubcarrier = app.NumberofSubcarrierEditField.Value;
           app.ModulationOrder = app.ModulationOrderEditField.Value;
           app.NumberofCP = app.NumberofCPEditField.Value;
           app.Framenumber = app.FrameNumberEditField.Value;
           app.Samplingrate  = app.SamplingRateHzEditField.Value;
           app.rollofffactor = app.RolloffFactorEditField.Value;
           app.Upsamplingrate = app.UpsamplingRateEditField.Value;
           [app.BitSequenOri, app.Pilotmod,app.TimeArrayInterp,app.DigitalOutputRoll,app.TXSymbolsOnSpecificCarrier,app.TXPAPR,app.SamplingPointNum,app.RFbandwidthGHz,app.Datarate] = Top_transmitter_gui (app.NumberofSubcarrier, app.ModulationOrder, app.NumberofCP, app.Framenumber, app.rollofffactor, app.Samplingrate, app.Upsamplingrate);
           app.PAPRdBEditField.Value = app.TXPAPR;
           app.SamplingPointNumberEditField.Value = app.SamplingPointNum;
           app.RFBandwidthGHzEditField.Value = app.RFbandwidthGHz;
           app.DataRatebpsEditField.Value = app.Datarate;
           plot(app.UIAxes,app.TimeArrayInterp,app.DigitalOutputRoll);
           scatter(app.UIAxes2, real(app.TXSymbolsOnSpecificCarrier),imag(app.TXSymbolsOnSpecificCarrier),'*');
           nfft = 2^(nextpow2(length(app.DigitalOutputRoll))-2)/2;
           win = window(@hann,nfft);
           olp = nfft/2;
           [xx, f1] = pwelch(app.DigitalOutputRoll,win,olp,nfft,app.Samplingrate);
           xx = fftshift(xx);
           xx = 10*log10(xx);
           max_xx = max(xx);
           max_xx = 0-max_xx;
           xx = xx+max_xx;
           f = f1-max(f1)/2;
           plot(app.UIAxes6,f,xx,'r');
        end

        % Button pushed function: SignalDemodulationButton
        function SignalDemodulationButtonPushed(app, event)
            [app.DigitalReceivedRoll, app.BitErrorNum, app.BitErrorRate, app.PackgeErrorNum, app.PackgeErrorRate,app.RXSymbolsOnSpecificCarrier] = Top_receiver_gui (app.NumberofSubcarrier, app.ModulationOrder, app.NumberofCP, app.Framenumber, app.rollofffactor, app.Samplingrate,app.Upsamplingrate ,app.Pilotmod,app.BitSequenOri,app.DigitalOutputRoll);
            plot(app.UIAxes3,app.TimeArrayInterp,app.DigitalReceivedRoll(1:length(app.TimeArrayInterp)));
            scatter(app.UIAxes4, real(app.RXSymbolsOnSpecificCarrier),imag(app.RXSymbolsOnSpecificCarrier),'*');
            app.BitErrorRateEditField.Value = app.BitErrorRate;
            app.PackgeErrorRateEditField.Value = app.PackgeErrorRate;
            [~,~,app.EVMpercent,app.nSymbol] =  Evm_Calculation (app.TXSymbolsOnSpecificCarrier.', app.RXSymbolsOnSpecificCarrier.');
            app.EVMEditField.Value = app.EVMpercent;
            nfft = 2^(nextpow2(length(app.DigitalOutputRoll))-2)/2;
            win = window(@hann,nfft);
            olp = nfft/2;
            [yy, f1] = pwelch(app.DigitalReceivedRoll,win,olp,nfft,app.Samplingrate);
            yy = fftshift(yy);
            yy = 10*log10(yy);
            max_yy = max(yy);
            max_yy = 0-max_yy;
            yy = yy+max_yy;
            f = f1-max(f1)/2;
            plot(app.UIAxes5,f,yy,'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ModulationOrderEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationOrderEditFieldLabel.Text = 'Modulation Order';
            app.ModulationOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulationOrderEditField.Value = 4;
            app.NumberofCPEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCPEditFieldLabel.Text = 'Number of CP';
            app.NumberofCPEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCPEditField.Value = 16;
            app.FrameNumberEditFieldLabel = uilabel(app.UIFigure);
            app.FrameNumberEditFieldLabel.Text = 'Frame Number';
            app.FrameNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameNumberEditField.Value = 10;
            app.ParameterofOFDMsingalLabel = uilabel(app.UIFigure);
            app.ParameterofOFDMsingalLabel.Text = 'Parameter of OFDM singal';
            app.ParameterofraisedcosinefilterLabel = uilabel(app.UIFigure);
            app.ParameterofraisedcosinefilterLabel.Text = 'Parameter of raised cosine filter';
            app.RolloffFactorEditFieldLabel = uilabel(app.UIFigure);
            app.RolloffFactorEditFieldLabel.Text = 'Rolloff Factor';
            app.RolloffFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.RolloffFactorEditField.Value = 0.25;
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHzEditField.Value = 1000000000;
            app.UpsamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.UpsamplingRateEditFieldLabel.Text = 'Up-sampling Rate';
            app.UpsamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpsamplingRateEditField.Value = 4;
            app.SignalModulationButton = uibutton(app.UIFigure, 'push');
            app.SignalModulationButton.ButtonPushedFcn = createCallbackFcn(app, @SignalModulationButtonPushed, true);
            app.SignalModulationButton.Text = 'Signal Modulation';
            app.SignalDemodulationButton = uibutton(app.UIFigure, 'push');
            app.SignalDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @SignalDemodulationButtonPushed, true);
            app.SignalDemodulationButton.Text = 'Signal Demodulation';
            app.BitErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitErrorRateEditField.ValueDisplayFormat = '%.3e';
            app.BitErrorRateEditField.Value = 1e-05;
            app.PackgeErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.PackgeErrorRateEditFieldLabel.Text = 'Packge Error Rate';
            app.PackgeErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transmitted Waveform')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Real Part')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Constellation')
            xlabel(app.UIAxes2, 'I-Path')
            ylabel(app.UIAxes2, 'Q-Path')
            app.PAPRdBEditFieldLabel = uilabel(app.UIFigure);
            app.PAPRdBEditFieldLabel.Text = 'PAPR (dB)';
            app.PAPRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.PAPRdBEditField.Value = 11;
            app.DataRatebpsEditFieldLabel = uilabel(app.UIFigure);
            app.DataRatebpsEditFieldLabel.Text = 'Data Rate (bps)';
            app.DataRatebpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EVMEditFieldLabel = uilabel(app.UIFigure);
            app.EVMEditFieldLabel.Text = 'EVM (%)';
            app.EVMEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Received Waveform')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Real Part')
            app.MatlabADSCadenceCosimulationPlatformLabel = uilabel(app.UIFigure);
            app.MatlabADSCadenceCosimulationPlatformLabel.Text = 'Matlab-ADS-Cadence Co-simulation Platform';
            app.MatlabADSCadenceCosimulationPlatformLabel_2 = uilabel(app.UIFigure);
            app.MatlabADSCadenceCosimulationPlatformLabel_2.Text = 'HKUST-Qualcomm Optical Wirelss Lab (HQOWL),   Hong Kong University of Science and Technology (HKUST)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'HQOWL.png';
            app.NumberofSubcarrierEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSubcarrierEditFieldLabel.Text = 'Number of Subcarrier';
            app.NumberofSubcarrierEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSubcarrierEditField.Value = 256;
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Constellation')
            xlabel(app.UIAxes4, 'I-Path')
            ylabel(app.UIAxes4, 'Q-Path')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Received Signal Spectrum')
            xlabel(app.UIAxes5, 'Frequency (GHz)')
            ylabel(app.UIAxes5, 'Normalized PSD (dB)')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Transmitted Signal Spectrum')
            xlabel(app.UIAxes6, 'Frequency (GHz)')
            ylabel(app.UIAxes6, 'Normalized PSD (dB)')
            app.RFBandwidthGHzEditFieldLabel = uilabel(app.UIFigure);
            app.RFBandwidthGHzEditFieldLabel.Text = 'RF Bandwidth (GHz)';
            app.RFBandwidthGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.RFBandwidthGHzEditField.Value = 0.5;
            app.CharacteristicsofsignalLabel = uilabel(app.UIFigure);
            app.CharacteristicsofsignalLabel.Text = 'Characteristics of signal';
            app.ParameterofreceivedsingalLabel = uilabel(app.UIFigure);
            app.ParameterofreceivedsingalLabel.Text = 'Parameter of received singal';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'HKUST.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Line.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'Line2.png';
            app.SamplingPointNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingPointNumberEditFieldLabel.Text = 'Sampling Point Number';
            app.SamplingPointNumberEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Ver2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end